classdef Ceparum_Echo_2017b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FinishButton                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        EchoDelayListBoxLabel           matlab.ui.control.Label
        EchoDelayListBox                matlab.ui.control.ListBox
        EchoLevelSpinnerLabel           matlab.ui.control.Label
        EchoLevelSpinner                matlab.ui.control.Spinner
        SystemPoleLocationSpinnerLabel  matlab.ui.control.Label
        SystemPoleLocationSpinner       matlab.ui.control.Spinner
        FFTLengthSpinnerLabel           matlab.ui.control.Label
        FFTLengthSpinner                matlab.ui.control.Spinner
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = Process(app)
                       
             hold( app.UIAxes1,'off');
             hold( app.UIAxes2,'off');
              hold( app.UIAxes3,'off');
            % DSP MSC/BSC course
            % Demo program by Hamid Sheikhzadeh, March, 2021
            % How multipath echo shows up in Cepstrum
            Pole=app.SystemPoleLocationSpinner.Value; % a single pole
            Gain=1;
            A=[1 Pole];
             
            B=Gain;
            EchoGain= app.EchoLevelSpinner.Value;
            EchoDelay = str2num(app.EchoDelayListBox.Value); %  16 24 32 % Echo delay
            FftLen=app.FFTLengthSpinner.Value; % EchoDelay*8;
            inp=[1 zeros(1,FftLen-1)]; % Delta in time
            Source=filter(B,A,inp); % Impulse response of a single pole
            x=[Source  zeros(1,EchoDelay)]+ [zeros(1,EchoDelay) EchoGain*Source]; % Add Echo
            
            plot(app.UIAxes1, x);grid(app.UIAxes1,'on' );
            title(app.UIAxes1, 'Signal+echo at sample ');
            xhat=cceps(x); % Complex Cepstrum
            
            plot(app.UIAxes2, xhat,'b.-');grid(app.UIAxes2,'on');hold(app.UIAxes2,'on');
            Line=linspace(0,1,5);
            for k=1:fix(length(inp)/EchoDelay)
                CepsGain= (EchoGain)^k/k * (-1)^(k+1);
                plot(app.UIAxes2, ones(1,5)*(EchoDelay*k+1),Line*CepsGain,'r.-')
            end
            title(app.UIAxes2, 'Complex Cepstrum of signal+echo ');
            RecoveredSource= icceps(xhat(1:EchoDelay));
            RecoveredSource= RecoveredSource*Source(1)/RecoveredSource(1);
            plot(app.UIAxes3,Source(1:EchoDelay),'go-');
            hold(app.UIAxes3,'on'); grid(app.UIAxes3,'on');
            plot(app.UIAxes3,RecoveredSource,'r*-');
            title(app.UIAxes3, 'Source (GREEN) and Recovered Signal (RED)');             
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Process(app)
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
            close all;
        end

        % Value changed function: EchoDelayListBox, 
        % EchoLevelSpinner, FFTLengthSpinner, 
        % SystemPoleLocationSpinner
        function EchoDelayListBoxValueChanged(app, event)
            value = app.EchoDelayListBox.Value;
            Process(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' DSP MSC/BSC course ,  Demo program by Hamid Sheikhzadeh, Mar. 2021,      How First-order  echo shows up in Cepstrum using a single-pole Channel';
            app.EchoDelayListBoxLabel = uilabel(app.UIFigure);
            app.EchoDelayListBoxLabel.Text = 'Echo Delay';
            app.EchoDelayListBox = uilistbox(app.UIFigure);
            app.EchoDelayListBox.Items = {'4', '8', '16', '24', '32', '64', '128'};
            app.EchoDelayListBox.ValueChangedFcn = createCallbackFcn(app, @EchoDelayListBoxValueChanged, true);
            app.EchoDelayListBox.Value = '16';
            app.EchoLevelSpinnerLabel = uilabel(app.UIFigure);
            app.EchoLevelSpinnerLabel.Text = 'Echo Level';
            app.EchoLevelSpinner = uispinner(app.UIFigure);
            app.EchoLevelSpinner.Step = 0.05;
            app.EchoLevelSpinner.Limits = [0.05 1];
            app.EchoLevelSpinner.ValueChangedFcn = createCallbackFcn(app, @EchoDelayListBoxValueChanged, true);
            app.EchoLevelSpinner.Value = 0.9;
            app.SystemPoleLocationSpinnerLabel = uilabel(app.UIFigure);
            app.SystemPoleLocationSpinnerLabel.Text = 'System Pole Location';
            app.SystemPoleLocationSpinner = uispinner(app.UIFigure);
            app.SystemPoleLocationSpinner.Step = 0.05;
            app.SystemPoleLocationSpinner.Limits = [0.1 0.95];
            app.SystemPoleLocationSpinner.ValueChangedFcn = createCallbackFcn(app, @EchoDelayListBoxValueChanged, true);
            app.SystemPoleLocationSpinner.Value = 0.7;
            app.FFTLengthSpinnerLabel = uilabel(app.UIFigure);
            app.FFTLengthSpinnerLabel.Text = 'FFT Length';
            app.FFTLengthSpinner = uispinner(app.UIFigure);
            app.FFTLengthSpinner.Step = 32;
            app.FFTLengthSpinner.Limits = [16 2048];
            app.FFTLengthSpinner.RoundFractionalValues = 'on';
            app.FFTLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @EchoDelayListBoxValueChanged, true);
            app.FFTLengthSpinner.Value = 128;
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'n')
            ylabel(app.UIAxes1, 'x[n]')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'n')
            ylabel(app.UIAxes2, 'x_hat[n]')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'n')
            ylabel(app.UIAxes3, 'Recovered Signal')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ceparum_Echo_2017b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end