classdef Ceparum_Speech < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Label                    matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        DPlotTab                 matlab.ui.container.Tab
        UIAxes4                  matlab.ui.control.UIAxes
        ProcessTab               matlab.ui.container.Tab
        UIAxes1                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        SampleStartSpinnerLabel  matlab.ui.control.Label
        SampleStartSpinner       matlab.ui.control.Spinner
        SampleEndSpinnerLabel    matlab.ui.control.Label
        SampleEndSpinner         matlab.ui.control.Spinner
        FinishButton             matlab.ui.control.Button
        FrameLengthLabel         matlab.ui.control.Label
        FrameLength              matlab.ui.control.Spinner
        NoFramesAveragedLabel    matlab.ui.control.Label
        NumFrmsAvg               matlab.ui.control.Spinner
        FFTLenSpinnerLabel       matlab.ui.control.Label
        FFTLenSpinner            matlab.ui.control.Spinner
    end

    
    methods (Access = private)
        
        function results = Process(app)
            
            hold( app.UIAxes1,'off');
            hold( app.UIAxes2,'off');
            hold( app.UIAxes3,'off');
            % DSP MSC course
            % Demo program on Speech Complex Cepstrum 
            % by Hamid Sheikhzadeh, Nov.  2020
                      
            clc;
             
            FILE='speech_dft.wav';
            [inp,Fs]=audioread(FILE);
            SampleStart= app.SampleStartSpinner.Value;
            SampleEnd= app.SampleEndSpinner.Value;
            
            inp=inp(SampleStart:SampleEnd); %15000:38000); %
            
            t=1/Fs*(0:1:length(inp)-1)*1000;
            plot(app.UIAxes1,t,inp);grid(app.UIAxes1,'on')
            xlabel(app.UIAxes1,'Time (mSec)');
            title(app.UIAxes1,'Time Waveform');
            
            %
            %------------------------------------------------------
            frm_len= app.FrameLength.Value; 
            FFT_LEN=app.FFTLenSpinner.Value;
            if FFT_LEN<frm_len
                FFT_LEN=frm_len;app.FrameLength.Value=frm_len;
            end
            win=hamming(frm_len);
            frm_shift=fix(frm_len*.5);
            num_frames=fix(length(inp)/frm_shift)-1;
            %
            start=1;
            %
            cc=zeros(num_frames,FFT_LEN);
            dc=0;
            first_plt=1;
            NumFrmsAveraged= app.NumFrmsAvg.Value; 
            for k=1:num_frames
                inp_frame=[inp(start:start+frm_len-1)];
                cc(k,:)=cceps(inp_frame,FFT_LEN);
                if  k>NumFrmsAveraged && mod(k,NumFrmsAveraged)==1
                    if first_plt==0,dc=dc+ max(abs(avg_cc(1:end)))/2;end
                    avg_cc=sum(log10(abs(cc(k-NumFrmsAveraged:k,1:end))));
                    plot(app.UIAxes2,avg_cc(1:end/4)+dc,'-');
                    hold(app.UIAxes2,'on');
                    first_plt=0;
                end
                start=start+frm_shift;
            end
            %
            grid(app.UIAxes2,'on');
            title(app.UIAxes2,'Avearaged Magnitude Cepstrum');
            xlabel(app.UIAxes2,'Quefrency (Samples)');
            ylabel(app.UIAxes2,'Frame Number');
            
            frm_time=(0:1:num_frames-1)/Fs*frm_shift*1000;
            surf(app.UIAxes4,frm_time,99:FFT_LEN/2-1,10*((cc(:,100:end/2)))','EdgeColor','none');
            axis(app.UIAxes4,'xy');
            axis(app.UIAxes4,'tight'); colormap(app.UIAxes4,'jet'); view(app.UIAxes4,[-150,50]);
            xlabel(app.UIAxes4,'Time (mSec)');
            ylabel(app.UIAxes4,'Qufrency (Samples)');
            
            plot(app.UIAxes3,frm_time,cc(:,1),'r');
            title(app.UIAxes3,'Log Energy, 1st CC');
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Process(app)
        end

        % Value changed function: FFTLenSpinner, FrameLength, 
        % NumFrmsAvg, SampleEndSpinner, SampleStartSpinner
        function SampleStartSpinnerValueChanged(app, event)
            value = app.SampleStartSpinner.Value;
            Process(app);
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
             delete(app)
            close all;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' DSP MSC/BSC course ,  Demo program by Hamid Sheikhzadeh, Dec..  2020, Complex Cepstrum of Speech Waveform';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DPlotTab = uitab(app.TabGroup);
            app.DPlotTab.Title = '3DPlot';
            app.UIAxes4 = uiaxes(app.DPlotTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.UIAxes1 = uiaxes(app.ProcessTab);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            app.UIAxes2 = uiaxes(app.ProcessTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.ProcessTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.SampleStartSpinnerLabel = uilabel(app.ProcessTab);
            app.SampleStartSpinnerLabel.Text = 'Sample Start';
            app.SampleStartSpinner = uispinner(app.ProcessTab);
            app.SampleStartSpinner.Step = 1000;
            app.SampleStartSpinner.Limits = [0 Inf];
            app.SampleStartSpinner.ValueDisplayFormat = '%.0f';
            app.SampleStartSpinner.ValueChangedFcn = createCallbackFcn(app, @SampleStartSpinnerValueChanged, true);
            app.SampleStartSpinner.Value = 21000;
            app.SampleEndSpinnerLabel = uilabel(app.ProcessTab);
            app.SampleEndSpinnerLabel.Text = 'Sample End';
            app.SampleEndSpinner = uispinner(app.ProcessTab);
            app.SampleEndSpinner.Step = 1000;
            app.SampleEndSpinner.Limits = [0 Inf];
            app.SampleEndSpinner.ValueDisplayFormat = '%.0f';
            app.SampleEndSpinner.ValueChangedFcn = createCallbackFcn(app, @SampleStartSpinnerValueChanged, true);
            app.SampleEndSpinner.Value = 26000;
            app.FinishButton = uibutton(app.ProcessTab, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.FrameLengthLabel = uilabel(app.ProcessTab);
            app.FrameLengthLabel.Text = 'Frame Length';
            app.FrameLength = uispinner(app.ProcessTab);
            app.FrameLength.Step = 128;
            app.FrameLength.Limits = [128 2048];
            app.FrameLength.ValueChangedFcn = createCallbackFcn(app, @SampleStartSpinnerValueChanged, true);
            app.FrameLength.Value = 512;
            app.NoFramesAveragedLabel = uilabel(app.ProcessTab);
            app.NoFramesAveragedLabel.Text = 'No. Frames Averaged';
            app.NumFrmsAvg = uispinner(app.ProcessTab);
            app.NumFrmsAvg.Limits = [1 20];
            app.NumFrmsAvg.ValueChangedFcn = createCallbackFcn(app, @SampleStartSpinnerValueChanged, true);
            app.NumFrmsAvg.Value = 10;
            app.FFTLenSpinnerLabel = uilabel(app.ProcessTab);
            app.FFTLenSpinnerLabel.Text = 'FFT Len';
            app.FFTLenSpinner = uispinner(app.ProcessTab);
            app.FFTLenSpinner.Step = 512;
            app.FFTLenSpinner.Limits = [1024 8192];
            app.FFTLenSpinner.ValueChangedFcn = createCallbackFcn(app, @SampleStartSpinnerValueChanged, true);
            app.FFTLenSpinner.Value = 8192;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ceparum_Speech

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end