classdef EigenFunction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PlotButton                   matlab.ui.control.Button
        FinishButton                 matlab.ui.control.Button
        PoleLocationSliderLabel      matlab.ui.control.Label
        PoleLocationSlider           matlab.ui.control.Slider
        InputFreqkSpinnerLabel       matlab.ui.control.Label
        InputFreqkSpinner            matlab.ui.control.Spinner
        Freqej2pi16kLabel            matlab.ui.control.Label
        AbsEigenValueEditFieldLabel  matlab.ui.control.Label
        AbsEigenValueEditField       matlab.ui.control.NumericEditField
        InputTypeDropDownLabel       matlab.ui.control.Label
        InputTypeDropDown            matlab.ui.control.DropDown
        Label                        matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = Process(app)
            
            
            % DSP BSC course, Amirkabir University of Technology, EE Dept.
            % Demo program by Hamid Sheikhzadeh, Sept., 23, 2020
            %  Complex Exponentials are Eigen Functions of LTI Systems
            close all;
            figure(1);hold off
            hold( app.UIAxes,'off');
            hold( app.UIAxes2,'off');
            Time_len=128;j=sqrt(-1);
            Time_vector=0:1:Time_len;
            k= app.InputFreqkSpinner.Value; 
            N=16;
            w_freq= 2*pi/N*(0:1:N/2); % Complex Exponential Freq.
            ExpFreq=w_freq(k+1);
            InputType= app.InputTypeDropDown.Value;
            switch InputType
                case 'Complex Exp'
                    InputExpWave= exp(j*ExpFreq*Time_vector);
                case 'Sine Wave'
                    InputExpWave=sin(ExpFreq*Time_vector);
            end
            
            PoleRadius= app.PoleLocationSlider.Value;
            Numerator=1;
            Denominator= [ 1 -PoleRadius];
            OutputWave= filter(Numerator, Denominator,InputExpWave);
            %
            polarplot(angle(InputExpWave),abs(InputExpWave));
            [H]=freqz(Numerator,Denominator,w_freq);
            plot(app.UIAxes,w_freq,abs(H),'o-') 
            hold( app.UIAxes,'on');
            grid(app.UIAxes,'on');
            plot(app.UIAxes,w_freq(k+1),abs(H(k+1)) ,'r*');
            %
            plot(app.UIAxes2,Time_vector,abs(OutputWave),'b.-');
            hold( app.UIAxes2,'on');
            grid(app.UIAxes2,'on');
            % Find the Freq. Response at given input freq.
            % Eigen Value=   1/[1-a*z^(-1)] @ z= e^(j*w_freq) 
            EigValue= 1/(1- PoleRadius*exp(-j*ExpFreq)); 
            app.AbsEigenValueEditField.Value= abs(EigValue);
            plot(app.UIAxes2,Time_vector,abs(EigValue)*ones(length(Time_vector)),'r.-');
            %------------------------------------------------------
                   
            title(app.UIAxes,'Complex Exponential Input');
            xlabel(app.UIAxes2,'Time Index (n)');
            title(app.UIAxes2,'Abs of Convolution Output');
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Process(app)
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
            close all;
        end

        % Value changed function: InputTypeDropDown
        function PoleLocationSliderValueChanged(app, event)
            value = app.PoleLocationSlider.Value;
            Process(app)
        end

        % Callback function: InputFreqkSpinner, PlotButton, 
        % PoleLocationSlider
        function InputFreqkSpinnerValueChanged(app, event)
            value = app.InputFreqkSpinner.Value;
            Process(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @InputFreqkSpinnerValueChanged, true);
            app.PlotButton.Text = 'Plot';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.PoleLocationSliderLabel = uilabel(app.UIFigure);
            app.PoleLocationSliderLabel.Text = 'Pole Location';
            app.PoleLocationSlider = uislider(app.UIFigure);
            app.PoleLocationSlider.Limits = [0.1 1];
            app.PoleLocationSlider.ValueChangedFcn = createCallbackFcn(app, @InputFreqkSpinnerValueChanged, true);
            app.PoleLocationSlider.MinorTicks = [];
            app.PoleLocationSlider.Value = 0.5;
            app.InputFreqkSpinnerLabel = uilabel(app.UIFigure);
            app.InputFreqkSpinnerLabel.Text = 'Input  Freq k';
            app.InputFreqkSpinner = uispinner(app.UIFigure);
            app.InputFreqkSpinner.Limits = [0 8];
            app.InputFreqkSpinner.ValueChangedFcn = createCallbackFcn(app, @InputFreqkSpinnerValueChanged, true);
            app.InputFreqkSpinner.Value = 1;
            app.Freqej2pi16kLabel = uilabel(app.UIFigure);
            app.Freqej2pi16kLabel.Text = 'Freq= e^(j*2*pi/16*k)';
            app.AbsEigenValueEditFieldLabel = uilabel(app.UIFigure);
            app.AbsEigenValueEditFieldLabel.Text = 'Abs Eigen Value';
            app.AbsEigenValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputTypeDropDownLabel = uilabel(app.UIFigure);
            app.InputTypeDropDownLabel.Text = 'Input Type';
            app.InputTypeDropDown = uidropdown(app.UIFigure);
            app.InputTypeDropDown.Items = {'Complex Exp', 'Sine Wave', '', '', ''};
            app.InputTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PoleLocationSliderValueChanged, true);
            app.InputTypeDropDown.Value = 'Complex Exp';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'DSP BSC course, Amirkabir University of Technology, EE Dept.Demo program by Hamid Sheikhzadeh, Sept., 2020'; '             Complex Exponentials are Eigen Functions of LTI Systems'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EigenFunction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end