classdef Exponential_Pulse_2017b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FinishButton                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        SystemPoleLocationSpinnerLabel  matlab.ui.control.Label
        SystemPoleLocationSpinner       matlab.ui.control.Spinner
        TimeLengthSpinnerLabel          matlab.ui.control.Label
        TimeLengthSpinner               matlab.ui.control.Spinner
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = Process(app)
            
            hold( app.UIAxes1,'off');
            hold( app.UIAxes2,'off');
            hold( app.UIAxes3,'off');
            clc;
            % DSP MSC/BSC course, the CIC filter is indeed an exponential pulse in time using the IIR form. 
            % In the IIR form include a cascade Integration of a Comb filter and an Integrator (hence CIC).
            % Demo program by Hamid Sheikhzadeh, Oct. 20, , 2020
            
            n=0:1:app.TimeLengthSpinner.Value-1; % Time vector
            a=app.SystemPoleLocationSpinner.Value;  % @@@@ Play with this pole for [0 1] and see the effects
            x=a.^n; % Exponential Pulse in Time
            len=length(n);
            N=1024; % No. of Freq. points in DTFT
            plot(app.UIAxes1, n,x,'*-');grid(app.UIAxes1,'on')
            title(app.UIAxes1, 'Exponential pulse in time');
            % Now start the IIR form: first implement the numerator, next denominator,
            % then combine the two.
            % Numerator: comb filter H(z)=  1 -a^N.Z^(-N) --------------------------
            B=[1 zeros(1,len-1), -a.^len]; % ZT of Numerator; a comb filter
            w=2*pi/N*(0:1:N/2-1);
            H_Comb=freqz(B,1,w);
            plot(app.UIAxes2,w/pi, 20*log10(abs(H_Comb)),'r.-')
             hold(app.UIAxes2,'on'); grid(app.UIAxes2,'on')
            %
            % Now use the IIR form of Denominator H(z)= 1/[1 - a.Z^(-1) ] ------------
            A=[1 -a]; % ZT of Denuminator
            H_Ar=freqz(1,A,w);
            plot(app.UIAxes2,w/pi, 20*log10(abs(H_Ar)),'g.-')
            title(app.UIAxes2,'Amp. Resp. (dB) for comb filter (RED) and Integrator (GREEN)');
            H_Total=freqz(B,A,w);
            plot(app.UIAxes3,w/pi, 20*log10(abs(H_Total)),'b.-')      
            title(app.UIAxes3,'Amp. Resp. (dB) for total CIC filter');
            grid(app.UIAxes3,'on')
            zplane(B,A);title('Poles/Zeros of exponential pulse')

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Process(app)
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
            close all;
        end

        % Value changed function: SystemPoleLocationSpinner, 
        % TimeLengthSpinner
        function SystemPoleLocationSpinnerValueChanged(app, event)
            value = app.SystemPoleLocationSpinner.Value;
            Process(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {' DSP MSC/BSC course, the CIC filter is indeed an exponential pulse in time using the IIR form. '; '             In the IIR form include a cascade Integration of a Comb filter and an Integrator (hence CIC).'; '             Demo program by Hamid Sheikhzadeh, Oct. 20, , 2020'};
            app.SystemPoleLocationSpinnerLabel = uilabel(app.UIFigure);
            app.SystemPoleLocationSpinnerLabel.Text = 'System Pole Location';
            app.SystemPoleLocationSpinner = uispinner(app.UIFigure);
            app.SystemPoleLocationSpinner.Step = 0.01;
            app.SystemPoleLocationSpinner.Limits = [0.1 1];
            app.SystemPoleLocationSpinner.ValueChangedFcn = createCallbackFcn(app, @SystemPoleLocationSpinnerValueChanged, true);
            app.SystemPoleLocationSpinner.Value = 0.9;
            app.TimeLengthSpinnerLabel = uilabel(app.UIFigure);
            app.TimeLengthSpinnerLabel.Text = 'Time Length';
            app.TimeLengthSpinner = uispinner(app.UIFigure);
            app.TimeLengthSpinner.Limits = [4 32];
            app.TimeLengthSpinner.RoundFractionalValues = 'on';
            app.TimeLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @SystemPoleLocationSpinnerValueChanged, true);
            app.TimeLengthSpinner.Value = 8;
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'n')
            ylabel(app.UIAxes1, 'x[n]')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'w/pi')
            ylabel(app.UIAxes2, 'x_hat[n]')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'w/pi')
            ylabel(app.UIAxes3, 'Total Filter dB')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exponential_Pulse_2017b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end