classdef FIR_GLP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes1_1              matlab.ui.control.UIAxes
        UIAxes1_2              matlab.ui.control.UIAxes
        PlotButton             matlab.ui.control.Button
        FinishButton           matlab.ui.control.Button
        UIAxes1_3              matlab.ui.control.UIAxes
        UIAxes1_4              matlab.ui.control.UIAxes
        Label                  matlab.ui.control.Label
        UIAxes2_1              matlab.ui.control.UIAxes
        UIAxes2_2              matlab.ui.control.UIAxes
        UIAxes2_3              matlab.ui.control.UIAxes
        UIAxes2_4              matlab.ui.control.UIAxes
        UIAxes3_1              matlab.ui.control.UIAxes
        UIAxes3_2              matlab.ui.control.UIAxes
        UIAxes3_3              matlab.ui.control.UIAxes
        UIAxes3_4              matlab.ui.control.UIAxes
        TimeSeqEditFieldLabel  matlab.ui.control.Label
        TimeSeqEditField       matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        function results = Process(app)
            clc
            
            % DSP BSC course
            % Demo program by Hamid Sheikhzadeh, Nov. 2020
            % 4 types of FIR GLP
            %
            N=1024;
            HalfSeq=str2num(app.TimeSeqEditField.Value); % Choose any array you like [1 2 4], [1 2 3]
            Numerator(:,1)=[HalfSeq(1:end-1),HalfSeq(end),HalfSeq(end-1:-1:1),0,0]; % Type I
            Numerator(:,2)=[HalfSeq,HalfSeq(end:-1:1),0]; % Type II
            Numerator(:,3)=[HalfSeq,0,-HalfSeq(end:-1:1)]; % Type III
            Numerator(:,4)=[HalfSeq, -HalfSeq(end:-1:1),0]; % Type IV
            t=1:length(Numerator(:,1));
            W= linspace(0+pi/10000,2*pi-pi/10000,N); % Avoid minus infinities
            %
            for Type=1:4
                [H(:,Type)]=freqz(Numerator(:,Type),sum(abs(Numerator(:,Type))),W);
                figure(1);
                subplot(2,2,Type);
                zplane(Numerator(:,Type)');
                title(['Type ',num2str(Type),' Zeros']);
                % --------------- Impulse Resp. ---------------------
                Fig1= ['app.UIAxes1_',num2str(Type)];
                A= ['stem(',Fig1,',t,Numerator(:,Type));'];eval(A);
                Tit= [', Imp. Resp.: TYPE ',num2str(Type), ' GLP'];
                A= ['title(',Fig1,',Tit',')'];eval(A);
                % ------------------ Mag. Resp. --------------------
                Fig1= ['app.UIAxes2_',num2str(Type)];
                A= ['plot(',Fig1,',W,20*log10(abs(H(:,Type))) );'];eval(A);
                Tit= [', Mag. Resp.: TYPE ',num2str(Type), ' GLP'];
                A= ['title(',Fig1,',Tit',')'];eval(A);
                 % ------------------ Phase Resp. --------------------
                Fig1= ['app.UIAxes3_',num2str(Type)];
                A= ['plot(',Fig1,',W,phase(H(:,Type)) );'];eval(A);
                Tit= [', Phase Resp.: TYPE ',num2str(Type), ' GLP'];
                A= ['title(',Fig1,',Tit',')'];eval(A);            
            end
                
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Process(app)
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
        end

        % Callback function: PlotButton, TimeSeqEditField
        function PlotButtonPushed(app, event)
            Process(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1_1 = uiaxes(app.UIFigure);
            title(app.UIAxes1_1, ' ')
            xlabel(app.UIAxes1_1, '')
            ylabel(app.UIAxes1_1, 'Y')
            app.UIAxes1_2 = uiaxes(app.UIFigure);
            title(app.UIAxes1_2, '')
            xlabel(app.UIAxes1_2, '')
            ylabel(app.UIAxes1_2, 'Y')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.UIAxes1_3 = uiaxes(app.UIFigure);
            title(app.UIAxes1_3, '')
            xlabel(app.UIAxes1_3, ' ')
            ylabel(app.UIAxes1_3, 'Y')
            app.UIAxes1_4 = uiaxes(app.UIFigure);
            title(app.UIAxes1_4, '')
            xlabel(app.UIAxes1_4, '')
            ylabel(app.UIAxes1_4, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {' DSP BSC course Demo program by Hamid Sheikhzadeh, Nov. 2020.'; ' Freq Resp of a second order allpass filter. Effect of phase distortion on a pulse/sine by an allpass filter'};
            app.UIAxes2_1 = uiaxes(app.UIFigure);
            title(app.UIAxes2_1, ' ')
            xlabel(app.UIAxes2_1, '')
            ylabel(app.UIAxes2_1, 'Y')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, 'Y')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, '')
            xlabel(app.UIAxes2_3, '')
            ylabel(app.UIAxes2_3, 'Y')
            app.UIAxes2_4 = uiaxes(app.UIFigure);
            title(app.UIAxes2_4, '')
            xlabel(app.UIAxes2_4, ' ')
            ylabel(app.UIAxes2_4, 'Y')
            app.UIAxes3_1 = uiaxes(app.UIFigure);
            title(app.UIAxes3_1, ' ')
            xlabel(app.UIAxes3_1, '')
            ylabel(app.UIAxes3_1, 'Y')
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, '')
            xlabel(app.UIAxes3_2, '')
            ylabel(app.UIAxes3_2, 'Y')
            app.UIAxes3_3 = uiaxes(app.UIFigure);
            title(app.UIAxes3_3, '')
            xlabel(app.UIAxes3_3, '')
            ylabel(app.UIAxes3_3, 'Y')
            app.UIAxes3_4 = uiaxes(app.UIFigure);
            title(app.UIAxes3_4, '')
            xlabel(app.UIAxes3_4, ' ')
            ylabel(app.UIAxes3_4, 'Y')
            app.TimeSeqEditFieldLabel = uilabel(app.UIFigure);
            app.TimeSeqEditFieldLabel.Text = 'Time Seq.';
            app.TimeSeqEditField = uieditfield(app.UIFigure, 'text');
            app.TimeSeqEditField.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.TimeSeqEditField.Value = '1 4 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FIR_GLP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end