classdef Gibbs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlotButton                  matlab.ui.control.Button
        FinishButton                matlab.ui.control.Button
        CutoffFreqSliderLabel       matlab.ui.control.Label
        CutoffFreqSlider            matlab.ui.control.Slider
        Label                       matlab.ui.control.Label
        SeriesMorderSpinnerLabel    matlab.ui.control.Label
        SeriesMorderSpinner         matlab.ui.control.Spinner
        HoldFigOnCheckBox           matlab.ui.control.CheckBox
        dBScaleCheckBox             matlab.ui.control.CheckBox
        PeakStopbandErrorEditFieldLabel  matlab.ui.control.Label
        PeakStopbandErrorEditField  matlab.ui.control.NumericEditField
        WindowListBoxLabel          matlab.ui.control.Label
        WindowListBox               matlab.ui.control.ListBox
        PeakPassbandErrorEditFieldLabel  matlab.ui.control.Label
        PeakPassbandErrorEditField  matlab.ui.control.NumericEditField
        Image                       matlab.ui.control.Image
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = Process(app)
            
            
            % DSP BSC course, Amirkabir University of Technology, EE Dept.
            % Demo program by Hamid Sheikhzadeh, Feb., 28, 2021
            % Gibbs Phenomena, effect of increasing DTFT order
            
            if app.HoldFigOnCheckBox.Value==1
                hold(app.UIAxes,'on');
            else
                hold(app.UIAxes,'off');
            end
            
            clc;
            Sinc_CutOff=app.CutoffFreqSlider.Value; % Cut-Off Freq., Normalized freq
            
            SincStartSample=app.SeriesMorderSpinner.Value;
            TimeSinc=sinc(-SincStartSample:Sinc_CutOff:SincStartSample);
            SincLen=length(TimeSinc);
            WinType= app.WindowListBox.Value;
            switch WinType
                case 'Rect'
                    Win=ones(1,SincLen);
                    SideLobeWidth= 2*pi/SincLen;
                case 'Hamming'
                    Win=hamming(SincLen);
                    SideLobeWidth= 4*pi/SincLen;
                case 'Hann'
                    Win=hann(SincLen);
                    SideLobeWidth= 4*pi/SincLen;
                case 'Bartlett'
                    Win=bartlett(SincLen);
                    SideLobeWidth= 4*pi/SincLen;
                case 'Blackman'
                    Win=blackman(SincLen);
                    SideLobeWidth= 6*pi/SincLen;
            end
            TimeSinc = TimeSinc(:).*Win(:);     % Window the time-domain Sinc
            FreqVecLen=SincLen*64;             % Length of the Freq. Vector
            Freq= 0:pi/FreqVecLen:pi;           % Freq. Vector
            % Find the sampled DTFT of the Sinc 
            [SincFreqResp]=freqz(TimeSinc,sum(TimeSinc),Freq);
            MagSincFreqResp= abs(SincFreqResp); % Magnitude Response
            % Find the Freq. of peak errors
            StopPeakFreq= Sinc_CutOff*pi + SideLobeWidth; % Freq. of peak error in stopband
            StopPeakFreqIndex=find(Freq >= StopPeakFreq);
            StopPeakFreqIndex= StopPeakFreqIndex(1);
            StopError= max(MagSincFreqResp(StopPeakFreqIndex:end)); % Max. Stopband Error
            %-------------------------------------
            PassPeakFreq= Sinc_CutOff*pi - SideLobeWidth; % Freq. of peak error in passband
            PassPeakFreqIndex=find(Freq >= PassPeakFreq);
            PassPeakFreqIndex= PassPeakFreqIndex(1);
            PassBandMean=mean(MagSincFreqResp(1:PassPeakFreqIndex/4));
            PassError= abs( MagSincFreqResp(PassPeakFreqIndex)-PassBandMean); % Max. Passband Error
            %------------------------------------------
            Time=0:1:SincLen-1; % Time vector
            Phasor= exp(1i*Sinc_CutOff*pi*Time);    % Phasor to modulate time-window 
            Win=Win(:).*Phasor(:);                  % Modulate time-window
            [WinFreqResp]=freqz(Win,sum(Win),Freq);
            WinFreqResp= abs(WinFreqResp);          % Window Freq. Response
            WinFreqResp= WinFreqResp/max(abs(WinFreqResp));
            if app.dBScaleCheckBox.Value==1         % Plot in dB
                
                plot(app.UIAxes,Freq/pi,20*log10(MagSincFreqResp));
                hold(app.UIAxes,'on');
                % Mark the peak errors with vertical lines
                plot(app.UIAxes,ones(1,10)*Freq(StopPeakFreqIndex)/pi,linspace(-80,5,10),'r-.');
                plot(app.UIAxes,ones(1,10)*Freq(PassPeakFreqIndex)/pi,linspace(-80,5,10),'k-.');
                plot(app.UIAxes,Freq(1:end)/pi,20*log10(WinFreqResp)-10,'g-.');
                legend(app.UIAxes,'Filter','Stop-Band Error','Pass-Band Error','Window')
            else
                plot(app.UIAxes,Freq/pi,(MagSincFreqResp)); % Linear scale plots
                hold(app.UIAxes,'on');
                plot(app.UIAxes,ones(1,10)*Freq(StopPeakFreqIndex)/pi,linspace(0,1.2,10),'r-.');
                plot(app.UIAxes,ones(1,10)*Freq(PassPeakFreqIndex)/pi,linspace(0,1.2,10),'k-.');
                plot(app.UIAxes,Freq(1:end)/pi,(WinFreqResp)/2,'g-.');
                legend(app.UIAxes,'Filter','Stop-Band Error','Pass-Band Error','Window')
            end
            StopError= 20*log10(StopError);   
            PassError= 20*log10(PassError); 
            app.PeakStopbandErrorEditField.Value= StopError;
            app.PeakPassbandErrorEditField.Value= PassError;
            grid(app.UIAxes,'on');
            title(app.UIAxes,'Gibbs Phenomena in Freq. Resp. after appying time window');
            
        end
        
        
        function results = func2(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Process(app)
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
            close all;
        end

        % Value changed function: CutoffFreqSlider, 
        % HoldFigOnCheckBox, SeriesMorderSpinner, WindowListBox, 
        % dBScaleCheckBox
        function CutoffFreqSliderValueChanged(app, event)
            value = app.CutoffFreqSlider.Value;
            Process(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.Text = 'Plot';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.CutoffFreqSliderLabel = uilabel(app.UIFigure);
            app.CutoffFreqSliderLabel.Text = 'Cut off Freq';
            app.CutoffFreqSlider = uislider(app.UIFigure);
            app.CutoffFreqSlider.Limits = [0.1 1];
            app.CutoffFreqSlider.ValueChangedFcn = createCallbackFcn(app, @CutoffFreqSliderValueChanged, true);
            app.CutoffFreqSlider.MinorTicks = [];
            app.CutoffFreqSlider.Value = 0.5;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' DSP BSC course, Amirkabir University of Technology, EE Dept.,Demo program by Hamid Sheikhzadeh, Feb., 28, 2021, Gibbs Phenomena, effect of increasing DTFT order';
            app.SeriesMorderSpinnerLabel = uilabel(app.UIFigure);
            app.SeriesMorderSpinnerLabel.Text = 'Series Order';
            app.SeriesMorderSpinner = uispinner(app.UIFigure);
            app.SeriesMorderSpinner.Step = 5;
            app.SeriesMorderSpinner.Limits = [3 256];
            app.SeriesMorderSpinner.RoundFractionalValues = 'on';
            app.SeriesMorderSpinner.ValueChangedFcn = createCallbackFcn(app, @CutoffFreqSliderValueChanged, true);
            app.SeriesMorderSpinner.Value = 16;
            app.HoldFigOnCheckBox = uicheckbox(app.UIFigure);
            app.HoldFigOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @CutoffFreqSliderValueChanged, true);
            app.HoldFigOnCheckBox.Text = 'Hold Fig.  On';
            app.dBScaleCheckBox = uicheckbox(app.UIFigure);
            app.dBScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @CutoffFreqSliderValueChanged, true);
            app.dBScaleCheckBox.Text = 'dB Scale';
            app.dBScaleCheckBox.Value = true;
            app.PeakStopbandErrorEditFieldLabel = uilabel(app.UIFigure);
            app.PeakStopbandErrorEditFieldLabel.Text = 'Peak Stopband Error';
            app.PeakStopbandErrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowListBoxLabel = uilabel(app.UIFigure);
            app.WindowListBoxLabel.Text = 'Window';
            app.WindowListBox = uilistbox(app.UIFigure);
            app.WindowListBox.Items = {'Rect', 'Bartlett', 'Hann', 'Hamming', 'Blackman', ''};
            app.WindowListBox.ValueChangedFcn = createCallbackFcn(app, @CutoffFreqSliderValueChanged, true);
            app.WindowListBox.Value = 'Rect';
            app.PeakPassbandErrorEditFieldLabel = uilabel(app.UIFigure);
            app.PeakPassbandErrorEditFieldLabel.Text = 'Peak Passband Error';
            app.PeakPassbandErrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'WindowTable.png';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Normalized Freq w')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gibbs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end