classdef GroupDelay_2017 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PlotButton                 matlab.ui.control.Button
        FinishButton               matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        InputFreq1SpinnerLabel     matlab.ui.control.Label
        InputFreq1Spinner          matlab.ui.control.Spinner
        InputFreq2SpinnerLabel     matlab.ui.control.Label
        InputFreq2Spinner          matlab.ui.control.Spinner
        InputLenSpinnerLabel       matlab.ui.control.Label
        InputLenSpinner            matlab.ui.control.Spinner
        InputsSpacingSpinnerLabel  matlab.ui.control.Label
        InputsSpacingSpinner       matlab.ui.control.Spinner
        UIAxes1                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes4                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = Process(app)
            
          
            % DSP BSC course, Hamid Sheikhzadeh, April 2021
            % Demonstrates the effect of Group Delay on Sine Pulses
            %
            % 
            % Create the signal.
            hold( app.UIAxes1,'off');
            hold( app.UIAxes2,'off');
            hold( app.UIAxes3,'off');
                                    
            Freq1= app.InputFreq1Spinner.Value; % Try 1/2
            Freq2= app.InputFreq2Spinner.Value; % Try 1/4
            w1= pi*Freq1; 
            w2= pi*Freq2;
            
            %-----------------------------------------------
            Len1= app.InputLenSpinner.Value;
            Len2=Len1;
            Spacing= app.InputsSpacingSpinner.Value;
            x = zeros(300,1);
            T1=1;T2=T1+Len1-1;Time1=T1:T2;
            T3=T2+Spacing;
            T4=T3+Len2-1;Time2=T3:T4;
            x(T1:T2) = cos(w1*(Time1)).*hamming(Len1)';
            x(Time2) = cos(w2*Time2).*hamming(Len2)';
            NumFreq=512;
            Freq=linspace(0,1,NumFreq);
            [H1]=freqz(x(Time1),1,Freq*pi);
            H2=freqz(x(Time2),1,Freq*pi);
            plot(app.UIAxes1, Freq,20*log10(abs(H1)),'g.-');
            hold(app.UIAxes1,'on');
            plot(app.UIAxes1,Freq,20*log10(abs(H2)),'m.-');
            title(app.UIAxes1,'Freq. Resp: Signal 1 (GREEN), Signal 2 (MAG)');
                      
            % Create an arbitrary group delay filter -----------------------        
            FilterOrder =50;
            gd = ones(size(Freq))*FilterOrder;
            %
            Slope=100;
            MaxGrpDelay= 150;
            gd(NumFreq/2-(Slope-1):NumFreq/2)=linspace(FilterOrder, MaxGrpDelay,Slope);
            gd(NumFreq/2+1:NumFreq/2+Slope)=linspace( MaxGrpDelay,FilterOrder,Slope);
            % plot(app.UIAxes2,Freq,gd,'r');hold(app.UIAxes2,'on')
            %-----------------------------------------------
            [Num,Den] = iirgrpdelay(FilterOrder,Freq,[0 1], gd);
            GD=grpdelay(Num,Den,NumFreq);
            plot(app.UIAxes2,Freq,GD,'b');hold(app.UIAxes2,'on')
            plot(app.UIAxes2,ones(1,10)*Freq1,linspace(0, MaxGrpDelay,10),'g.-');
            plot(app.UIAxes2,ones(1,10)*Freq2,linspace(0, MaxGrpDelay,10),'m.-');
            title(app.UIAxes2,'Filter Group Delay (Samples)')
            %------------------------------------------------------------
            y=filter(Num,Den,x);
            plot(app.UIAxes3,x); title(app.UIAxes3,'Input Signal');
            hold(app.UIAxes3,'on');plot(app.UIAxes3,x(Time1),'g');
            plot(app.UIAxes3,[zeros(T3-1,1); x(Time2)],'m');
            ylabel(app.UIAxes3,'Amplitude');
            %-------------------------------------------------------------
            plot(app.UIAxes4,y); title(app.UIAxes4,'Output Signal');
            xlabel(app.UIAxes4,'Samples'); ylabel(app.UIAxes4,'Amplitude');
            grid(app.UIAxes1,'on');grid(app.UIAxes2,'on');
            grid(app.UIAxes3,'on');grid(app.UIAxes4,'on');
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Process(app)
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
        end

        % Callback function: InputFreq1Spinner, InputFreq2Spinner, 
        % InputLenSpinner, InputsSpacingSpinner, PlotButton
        function PlotButtonPushed(app, event)
            Process(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' DSP BSC course, Hamid Sheikhzadeh, April 2021,  Demonstrates the effect of Group Delay on Sine Pulses';
            app.InputFreq1SpinnerLabel = uilabel(app.UIFigure);
            app.InputFreq1SpinnerLabel.Text = 'Input Freq1';
            app.InputFreq1Spinner = uispinner(app.UIFigure);
            app.InputFreq1Spinner.Step = 0.05;
            app.InputFreq1Spinner.Limits = [0 1];
            app.InputFreq1Spinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.InputFreq1Spinner.Value = 0.5;
            app.InputFreq2SpinnerLabel = uilabel(app.UIFigure);
            app.InputFreq2SpinnerLabel.Text = 'Input Freq2';
            app.InputFreq2Spinner = uispinner(app.UIFigure);
            app.InputFreq2Spinner.Step = 0.05;
            app.InputFreq2Spinner.Limits = [0 1];
            app.InputFreq2Spinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.InputFreq2Spinner.Value = 0.25;
            app.InputLenSpinnerLabel = uilabel(app.UIFigure);
            app.InputLenSpinnerLabel.Text = 'Input  Len';
            app.InputLenSpinner = uispinner(app.UIFigure);
            app.InputLenSpinner.Limits = [10 100];
            app.InputLenSpinner.RoundFractionalValues = 'on';
            app.InputLenSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.InputLenSpinner.Value = 40;
            app.InputsSpacingSpinnerLabel = uilabel(app.UIFigure);
            app.InputsSpacingSpinnerLabel.Text = 'Inputs Spacing';
            app.InputsSpacingSpinner = uispinner(app.UIFigure);
            app.InputsSpacingSpinner.Limits = [0 100];
            app.InputsSpacingSpinner.RoundFractionalValues = 'on';
            app.InputsSpacingSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.InputsSpacingSpinner.Value = 5;
            app.UIAxes1 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes1, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes3, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GroupDelay_2017

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end