classdef PhaseRespGD_2017 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlotButton                  matlab.ui.control.Button
        FinishButton                matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        Pole_ZeroMagSpinnerLabel    matlab.ui.control.Label
        Pole_ZeroMagSpinner         matlab.ui.control.Spinner
        Pole_ZeroAngelSpinnerLabel  matlab.ui.control.Label
        Pole_ZeroAngelSpinner       matlab.ui.control.Spinner
        UIAxes1                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes1_2                   matlab.ui.control.UIAxes
        UIAxes3_2                   matlab.ui.control.UIAxes
        UIAxes2_2                   matlab.ui.control.UIAxes
        UIAxes4_2                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = Process(app)
            close all
            % DSP BSC course
            % Demo program by Hamid Sheikhzadeh, April 2021to demonstrate
            % use of Phase Delay and Group Delay
            hold( app.UIAxes1,'off');
            hold( app.UIAxes2,'off');
            hold( app.UIAxes3,'off');
            hold( app.UIAxes4,'off');
            Pole= app.Pole_ZeroMagSpinner.Value;
            Omega=app.Pole_ZeroAngelSpinner.Value;
            Pole= Pole*exp(j*Omega);
            N=1024;
            w=2*pi/N*(-N/2:1:N/2-1);
            B=1;
            A=[1 -Pole];
            H1=freqz(B,A,w);
            plot(app.UIAxes1,w, phase(H1));
            title(app.UIAxes1,['Phase Resp, INSIDE Pole at ',num2str(Pole)])
            grid(app.UIAxes1,'on');
            Gd1= grpdelay(B,A,w);
            plot(app.UIAxes3,w, Gd1);
            title(app.UIAxes3,['Group Delay INSIDE Pole at ',num2str(Pole)])
            grid(app.UIAxes3,'on');
            %------------------------------------------------------
            B=1;A=[1 -1/Pole];
            H1=freqz(B,A,w);
            plot(app.UIAxes2,w, phase(H1),'r');
            title(app.UIAxes2,['Phase Resp, OUTSIDE Pole at ',num2str(1/Pole)])
            grid(app.UIAxes2,'on');
            Gd1= grpdelay(B,A,w);
            plot(app.UIAxes4,w, Gd1,'r');
            title(app.UIAxes4,['Group Delay OUTSIDE Pole at ',num2str(1/Pole)])
            grid(app.UIAxes4,'on');
            %----------------------------------------------------------
            A=1; B=[1 -Pole];
            H1=freqz(B,A,w);
            plot(app.UIAxes1_2,w, phase(H1));
            title(app.UIAxes1_2,['Phase Resp, INSIDE Zero at ',num2str(Pole)])
            grid(app.UIAxes1_2,'on');
            Gd1= grpdelay(B,A,w);
            plot(app.UIAxes3_2,w, Gd1);
            title(app.UIAxes3_2,['Group Delay INSIDE Zero at ',num2str(Pole)])
            grid(app.UIAxes3_2,'on');
            %------------------------------------------------------
            B=[1 -1/Pole]; A=1;
            H1=freqz(B,A,w);
            plot(app.UIAxes2_2,w, phase(H1),'r');
            title(app.UIAxes2_2,['Phase Resp, OUTSIDE Zero at ',num2str(1/Pole)])
            grid(app.UIAxes2_2,'on');
            Gd1= grpdelay(B,A,w);
            plot(app.UIAxes4_2,w, Gd1,'r');
            title(app.UIAxes4_2,['Group Delay OUTSIDE Zero at ',num2str(1/Pole)]);
            grid(app.UIAxes4_2,'on');
          
            %
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Process(app)
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
        end

        % Callback function: PlotButton, Pole_ZeroAngelSpinner, 
        % Pole_ZeroMagSpinner
        function PlotButtonPushed(app, event)
            Process(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'DSP BSC course,  Demo program by Hamid Sheikhzadeh, April 2021to demonstrate  Phase Delay and Group Delay';
            app.Pole_ZeroMagSpinnerLabel = uilabel(app.UIFigure);
            app.Pole_ZeroMagSpinnerLabel.Text = 'Pole_Zero Mag';
            app.Pole_ZeroMagSpinner = uispinner(app.UIFigure);
            app.Pole_ZeroMagSpinner.Step = 0.1;
            app.Pole_ZeroMagSpinner.Limits = [0 10];
            app.Pole_ZeroMagSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Pole_ZeroMagSpinner.Value = 0.5;
            app.Pole_ZeroAngelSpinnerLabel = uilabel(app.UIFigure);
            app.Pole_ZeroAngelSpinnerLabel.Text = 'Pole_Zero Angel';
            app.Pole_ZeroAngelSpinner = uispinner(app.UIFigure);
            app.Pole_ZeroAngelSpinner.Step = 0.1;
            app.Pole_ZeroAngelSpinner.Limits = [0 3.14159265358979];
            app.Pole_ZeroAngelSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Pole_ZeroAngelSpinner.Value = 1.5707963267949;
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, ' ')
            xlabel(app.UIAxes1, 'n')
            ylabel(app.UIAxes1, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, ' ')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes1_2 = uiaxes(app.UIFigure);
            title(app.UIAxes1_2, ' ')
            xlabel(app.UIAxes1_2, 'n')
            ylabel(app.UIAxes1_2, 'Y')
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3_2, ' ')
            ylabel(app.UIAxes3_2, 'Y')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes2_2, 'Y')
            app.UIAxes4_2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes4_2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhaseRespGD_2017

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end