classdef SinSample1_2017 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        sine1freqEditFieldLabel        matlab.ui.control.Label
        sine1freqEditField             matlab.ui.control.NumericEditField
        sine2freqEditFieldLabel        matlab.ui.control.Label
        sine2freqEditField             matlab.ui.control.NumericEditField
        NumberofPeriodsEditFieldLabel  matlab.ui.control.Label
        NumberofPeriodsEditField       matlab.ui.control.NumericEditField
        PlotButton                     matlab.ui.control.Button
        DecimationFactorSpinnerLabel   matlab.ui.control.Label
        DecimationFactorSpinner        matlab.ui.control.Spinner
        FinishButton                   matlab.ui.control.Button
        DemodFreq1TextAreaLabel        matlab.ui.control.Label
        DemodFreq1TextArea             matlab.ui.control.TextArea
        DemodFreq2TextAreaLabel        matlab.ui.control.Label
        DemodFreq2TextArea             matlab.ui.control.TextArea
        SamplingFreqEditFieldLabel     matlab.ui.control.Label
        SamplingFreqEditField          matlab.ui.control.NumericEditField
        DecimatedFsTextAreaLabel       matlab.ui.control.Label
        DecimatedFsTextArea            matlab.ui.control.TextArea
        Label                          matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = sample(app)
            
            
            % DSP MS/BSCC course, Amirkabir University of Technology, EE Dept.
            % Demo program by Hamid Sheikhzadeh, Mar., 2021
            % Notice the effect of decimation on sines
            % Aliasing appears as FREQUENCY MODULATION
            Freq0=app.sine1freqEditField.Value;  
            Freq1= app.sine2freqEditField.Value;
            NumFreqPoints=1024;
            hold( app.UIAxes,'off');
            hold( app.UIAxes2,'off');
            Fs=app.SamplingFreqEditField.Value;
            DecimationFactor= app.DecimationFactorSpinner.Value;
            fmin=min(Freq0,Freq1);
            NumTimePoints=fix(Fs/fmin*app.NumberofPeriodsEditField.Value); 
            time_vector=0:1/Fs:NumTimePoints/Fs;
            InputWave=sin(Freq0*2*pi*time_vector+pi/3)+ 4*sin(Freq1*2*pi*time_vector+pi/5);
            %
            plot(app.UIAxes,time_vector,InputWave,'.-');
            hold( app.UIAxes,'on');
            grid(app.UIAxes,'on');
            
            Fs_dec=Fs/DecimationFactor;
            app.DecimatedFsTextArea.Value= num2str(Fs_dec);
            InputWave_dec=InputWave(1:DecimationFactor:end);
            time_vector_dec=time_vector(1:DecimationFactor:end);
            k0=fix(Freq0/Fs_dec);k1=fix(Freq1/Fs_dec);
            Freq0_new= rem(Freq0,Fs_dec); Freq1_new= rem(Freq1,Fs_dec)
            Freq0_new= min(Freq0_new, abs( Freq0-(k0+1)*Fs_dec) );
            Freq1_new= min(Freq1_new, abs( Freq1-(k1+1)*Fs_dec) );
            app.DemodFreq1TextArea.Value= num2str(Freq0_new);
            
            app.DemodFreq2TextArea.Value=  num2str(Freq1_new);
            plot(app.UIAxes,time_vector_dec,InputWave_dec,'r.-');
            %------------------------------------------------------
            [Input_DTFT,Freq1Hz]=freqz(InputWave,1,NumFreqPoints,Fs);
            plot(app.UIAxes2, Freq1Hz,20*log10(abs(Input_DTFT)),'b.-');
            grid(app.UIAxes2,'on');
            hold(app.UIAxes2,'on');
            [Input_dec_DTFT,f2Hz]=freqz(InputWave_dec,1,NumFreqPoints,Fs_dec);
            plot(app.UIAxes2,f2Hz,20*log10(abs(Input_dec_DTFT*DecimationFactor)),'r.-');
            
            plot(app.UIAxes3,f2Hz,20*log10(abs(Input_dec_DTFT*DecimationFactor)),'r.-');
            grid(app.UIAxes3,'on');
            xlabel(app.UIAxes3,'Freq. (Hz)');
            title(app.UIAxes3,'DTFT mag. of decimated version');
            title(app.UIAxes,'Input sine (BLUE) and decimated versions (RED)');
            
            xlabel(app.UIAxes2,'Freq. (Hz)');
            title(app.UIAxes2,'DTFT mag. of input (BLUE) and decimated versions (RED)');
        end
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            sample(app)
        end

        % Callback function: DecimationFactorSpinner, 
        % NumberofPeriodsEditField, PlotButton, 
        % SamplingFreqEditField, sine1freqEditField, 
        % sine2freqEditField
        function sine1freqEditFieldValueChanged(app, event)
            value = app.sine1freqEditField.Value;
            sample(app);
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.sine1freqEditFieldLabel = uilabel(app.UIFigure);
            app.sine1freqEditFieldLabel.Text = 'sine 1  freq';
            app.sine1freqEditField = uieditfield(app.UIFigure, 'numeric');
            app.sine1freqEditField.Limits = [0 500];
            app.sine1freqEditField.ValueDisplayFormat = '%.3f';
            app.sine1freqEditField.ValueChangedFcn = createCallbackFcn(app, @sine1freqEditFieldValueChanged, true);
            app.sine1freqEditField.Value = 125;
            app.sine2freqEditFieldLabel = uilabel(app.UIFigure);
            app.sine2freqEditFieldLabel.Text = 'sine 2  freq';
            app.sine2freqEditField = uieditfield(app.UIFigure, 'numeric');
            app.sine2freqEditField.Limits = [0 500];
            app.sine2freqEditField.ValueDisplayFormat = '%.3f';
            app.sine2freqEditField.ValueChangedFcn = createCallbackFcn(app, @sine1freqEditFieldValueChanged, true);
            app.sine2freqEditField.Value = 75;
            app.NumberofPeriodsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofPeriodsEditFieldLabel.Text = 'Number of Periods';
            app.NumberofPeriodsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPeriodsEditField.Limits = [1 1000];
            app.NumberofPeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @sine1freqEditFieldValueChanged, true);
            app.NumberofPeriodsEditField.Value = 200;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @sine1freqEditFieldValueChanged, true);
            app.PlotButton.Text = 'Plot';
            app.DecimationFactorSpinnerLabel = uilabel(app.UIFigure);
            app.DecimationFactorSpinnerLabel.Text = {'Decimation '; 'Factor'};
            app.DecimationFactorSpinner = uispinner(app.UIFigure);
            app.DecimationFactorSpinner.Step = 3;
            app.DecimationFactorSpinner.Limits = [1 50];
            app.DecimationFactorSpinner.ValueChangedFcn = createCallbackFcn(app, @sine1freqEditFieldValueChanged, true);
            app.DecimationFactorSpinner.Value = 5;
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.DemodFreq1TextAreaLabel = uilabel(app.UIFigure);
            app.DemodFreq1TextAreaLabel.Text = 'Demod Freq. 1';
            app.DemodFreq1TextArea = uitextarea(app.UIFigure);
            app.DemodFreq2TextAreaLabel = uilabel(app.UIFigure);
            app.DemodFreq2TextAreaLabel.Text = 'Demod Freq. 2';
            app.DemodFreq2TextArea = uitextarea(app.UIFigure);
            app.SamplingFreqEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFreqEditFieldLabel.Text = 'Sampling Freq';
            app.SamplingFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFreqEditField.Limits = [1000 10000];
            app.SamplingFreqEditField.ValueChangedFcn = createCallbackFcn(app, @sine1freqEditFieldValueChanged, true);
            app.SamplingFreqEditField.Value = 1000;
            app.DecimatedFsTextAreaLabel = uilabel(app.UIFigure);
            app.DecimatedFsTextAreaLabel.Text = 'Decimated Fs';
            app.DecimatedFsTextArea = uitextarea(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {' DSP MS/BSCC course, Amirkabir University of Technology, EE Dept.'; '            Demo program by Hamid Sheikhzadeh, Mar., 2021    Notice the effect of decimation on sinesAliasing appears as FREQUENCY MODULATION'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Waveform')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Freq. Domain')
            xlabel(app.UIAxes2, 'Freq')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SinSample1_2017

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end