classdef TimeWindowing2017 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PlotButton               matlab.ui.control.Button
        FinishButton             matlab.ui.control.Button
        WindowLengthSliderLabel  matlab.ui.control.Label
        WindowLengthSlider       matlab.ui.control.Slider
        Label                    matlab.ui.control.Label
        HoldFigOnCheckBox        matlab.ui.control.CheckBox
        dBScaleCheckBox          matlab.ui.control.CheckBox
        WindowListBoxLabel       matlab.ui.control.Label
        WindowListBox            matlab.ui.control.ListBox
        K1Label                  matlab.ui.control.Label
        K1Spinner                matlab.ui.control.Spinner
        w12pik164Label           matlab.ui.control.Label
        K2SpinnerLabel           matlab.ui.control.Label
        K2Spinner                matlab.ui.control.Spinner
        w22pik164Label           matlab.ui.control.Label
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes1                  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = Process(app)
            
            
            % DSP BSC course, Amirkabir University of Technology, EE Dept.
            % Demo program by Hamid Sheikhzadeh, Mar.,6, 2021
            % Effect of Time-Windowing on Freq. Resolution
            
            if app.HoldFigOnCheckBox.Value==1
                hold(app.UIAxes2,'on');  hold(app.UIAxes3,'on');
            else
                hold(app.UIAxes2,'off');  hold(app.UIAxes3,'off');
            end
            
            clc;
            WindowLength= fix(app.WindowLengthSlider.Value);
            TimeLength=1024;
            n=0:1:TimeLength-1;
            w1=app.K1Spinner.Value*2*pi/64;
            w2=app.K2Spinner.Value*2*pi/64;
            TimeSine=sin(w1*n)+ sin(w2*n);
            WinType= app.WindowListBox.Value;
            switch WinType
                case 'Rect'
                    Win=ones(1,WindowLength);
                    
                case 'Hamming'
                    Win=hamming(WindowLength);
                    
                case 'Hann'
                    Win=hann(WindowLength);
                    
                case 'Bartlett'
                    Win=bartlett(WindowLength);
                    
                case 'Blackman'
                    Win=blackman(WindowLength);
                    
            end
            WindowedSignal =TimeSine(1:WindowLength)'.*Win(:);     % Window the time-domain Sinc
            FreqVecLen=1024;             % Length of the Freq. Vector
            Freq= -pi:pi/FreqVecLen:pi;           % Freq. Vector
            %-------------------- Long Sines Responses ------------------
            [LongFreqResp]=freqz(TimeSine,1,Freq);
            MagLongFreqResp= abs(LongFreqResp); % Magnitude Response
            MagLongFreqResp =  MagLongFreqResp/max( MagLongFreqResp);
            %---------------------- Windoed Sines Responses --------------
            [WindowedFreqResp]=freqz(WindowedSignal,1,Freq);
            MagWindowedFreqResp= abs(WindowedFreqResp); % Magnitude Response
            MagWindowedFreqResp =  MagWindowedFreqResp/max( MagWindowedFreqResp);
            %--------------------- Window Freq. Response ---------------
            FreqWin= -pi:pi/FreqVecLen:pi;           % Freq. Vector
            [WinFreqResp]=freqz(Win,1,FreqWin);
            WinFreqResp= abs(WinFreqResp);          % Window Freq. Response
            WinFreqResp= WinFreqResp/max(abs(WinFreqResp));
            %------------------ Modulate Time-Window to locate it on one of the sines 
            Phasor= exp(1i*w1*(0:1:WindowLength-1)); % Phasor signal for modulation
            ModWin= Win(:).*Phasor(:); % Freq. Modulation using the phasor
            [ModWinFreqResp]=freqz(ModWin,1,FreqWin);
            ModWinFreqResp= abs(ModWinFreqResp)/max(abs(ModWinFreqResp));
            %--------------------------------------------------------
            if app.dBScaleCheckBox.Value==1         % Plot in dB
                plot(app.UIAxes1,Freq/pi,20*log10(MagLongFreqResp));
                title(app.UIAxes1,'Freq. Resp. of Long Sinosoids')
                grid(app.UIAxes1,'on')
                %-----------------------------------------------------
                plot(app.UIAxes2,Freq/pi,20*log10(MagWindowedFreqResp));
                title(app.UIAxes2,'Freq. Resp. of Time-Windowed Sinosoids')
                grid(app.UIAxes2,'on')
                %------------------------------------------------
                plot(app.UIAxes3,FreqWin/pi,20*log10(WinFreqResp));
                title(app.UIAxes3,'Freq. Resp. of Time Window')
                grid(app.UIAxes3,'on')
            else     % Linear scale plots
                plot(app.UIAxes1,Freq/pi,(MagLongFreqResp));
                title(app.UIAxes1,'Freq. Resp. of Long Sinosoids')
                grid(app.UIAxes1,'on')
                %-----------------------------------------------
                plot(app.UIAxes2,Freq/pi,(MagWindowedFreqResp)); 
                title(app.UIAxes2,'Freq. Resp. of Time-Windowed Sinosoids')
                grid(app.UIAxes2,'on')
                hold(app.UIAxes2,'on');
                plot(app.UIAxes2,FreqWin/pi,(ModWinFreqResp));
                legend(app.UIAxes2,'Windowed Sines','Time Window')
                %-------------------------------------------------------
                plot(app.UIAxes3,FreqWin/pi,(WinFreqResp));
                title(app.UIAxes3,'Freq. Resp. of Time Window')
                grid(app.UIAxes3,'on')
            end
            grid(app.UIAxes2,'on');
            title(app.UIAxes2,'Sine Freq. Resp. after appying time window');
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Process(app)
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
            close all;
        end

        % Value changed function: HoldFigOnCheckBox, K1Spinner, 
        % K2Spinner, WindowLengthSlider, WindowListBox, 
        % dBScaleCheckBox
        function CutoffFreqSliderValueChanged(app, event)
            value = app.WindowLengthSlider.Value;
            Process(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.Text = 'Plot';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.WindowLengthSliderLabel = uilabel(app.UIFigure);
            app.WindowLengthSliderLabel.Text = 'Window Length';
            app.WindowLengthSlider = uislider(app.UIFigure);
            app.WindowLengthSlider.Limits = [16 128];
            app.WindowLengthSlider.ValueChangedFcn = createCallbackFcn(app, @CutoffFreqSliderValueChanged, true);
            app.WindowLengthSlider.MinorTicks = [];
            app.WindowLengthSlider.Value = 64;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'DSP BSC course, Amirkabir University of Technology, EE Dept.,  Demo program by Hamid Sheikhzadeh, Mar.,6, 2021,  Effect of Time-Windowing on Freq. Resolution';
            app.HoldFigOnCheckBox = uicheckbox(app.UIFigure);
            app.HoldFigOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @CutoffFreqSliderValueChanged, true);
            app.HoldFigOnCheckBox.Text = 'Hold Fig.  On';
            app.dBScaleCheckBox = uicheckbox(app.UIFigure);
            app.dBScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @CutoffFreqSliderValueChanged, true);
            app.dBScaleCheckBox.Text = 'dB Scale';
            app.dBScaleCheckBox.Value = true;
            app.WindowListBoxLabel = uilabel(app.UIFigure);
            app.WindowListBoxLabel.Text = 'Window';
            app.WindowListBox = uilistbox(app.UIFigure);
            app.WindowListBox.Items = {'Rect', 'Bartlett', 'Hann', 'Hamming', 'Blackman', ''};
            app.WindowListBox.ValueChangedFcn = createCallbackFcn(app, @CutoffFreqSliderValueChanged, true);
            app.WindowListBox.Value = 'Rect';
            app.K1Label = uilabel(app.UIFigure);
            app.K1Label.Text = 'K1';
            app.K1Spinner = uispinner(app.UIFigure);
            app.K1Spinner.Limits = [0 32];
            app.K1Spinner.ValueChangedFcn = createCallbackFcn(app, @CutoffFreqSliderValueChanged, true);
            app.K1Spinner.Value = 18;
            app.w12pik164Label = uilabel(app.UIFigure);
            app.w12pik164Label.Text = 'w1= 2*pi*k1/64';
            app.K2SpinnerLabel = uilabel(app.UIFigure);
            app.K2SpinnerLabel.Text = 'K2';
            app.K2Spinner = uispinner(app.UIFigure);
            app.K2Spinner.Limits = [0 32];
            app.K2Spinner.ValueChangedFcn = createCallbackFcn(app, @CutoffFreqSliderValueChanged, true);
            app.K2Spinner.Value = 20;
            app.w22pik164Label = uilabel(app.UIFigure);
            app.w22pik164Label.Text = 'w2= 2*pi*k1/64';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Normalized Freq w')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'Normalized Freq w')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'Normalized Freq w')
            ylabel(app.UIAxes1, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TimeWindowing2017

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end