classdef convolution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PlotButton               matlab.ui.control.Button
        FinishButton             matlab.ui.control.Button
        PoleLocationSliderLabel  matlab.ui.control.Label
        PoleLocationSlider       matlab.ui.control.Slider
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = Convolve(app)
            
            
            % DSP BSC course, Amirkabir University of Technology, EE Dept.
            % Demo program by Hamid Sheikhzadeh, Sept., 23, 2020
            %  Convolution of a Moving Average (MA) and an exponential Wave
            
            hold( app.UIAxes,'off');
            hold( app.UIAxes2,'off');
            Time_len2=128;
            Time_vector2=0:1:Time_len2;
            MA_len=16;
            PoleRadius=app.PoleLocationSlider.Value;
            Time_vector1=1:MA_len;
            InputWave1= ones(1,MA_len);
            InputWave2= PoleRadius.^Time_vector2;
            %
            stem(app.UIAxes,Time_vector1,InputWave1,'.-');
            hold( app.UIAxes,'on');
            grid(app.UIAxes,'on');
            plot(app.UIAxes,Time_vector2,InputWave2,'r.-');
            %------------------------------------------------------
            Output= conv(InputWave1, InputWave2);
            stem(app.UIAxes2, Output,'b.-');
            grid(app.UIAxes2,'on');
            hold(app.UIAxes2,'on');
            
            title(app.UIAxes,'Inputs MA (BLUE) and Exponential (RED)');
            xlabel(app.UIAxes2,'Time Index (n)');
            title(app.UIAxes2,'Convolution Output');
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Convolve(app)
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
        end

        % Callback function: PlotButton, PoleLocationSlider
        function PoleLocationSliderValueChanged(app, event)
            value = app.PoleLocationSlider.Value;
            Convolve(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PoleLocationSliderValueChanged, true);
            app.PlotButton.Text = 'Plot';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.PoleLocationSliderLabel = uilabel(app.UIFigure);
            app.PoleLocationSliderLabel.Text = 'Pole Location';
            app.PoleLocationSlider = uislider(app.UIFigure);
            app.PoleLocationSlider.Limits = [0.1 1];
            app.PoleLocationSlider.ValueChangedFcn = createCallbackFcn(app, @PoleLocationSliderValueChanged, true);
            app.PoleLocationSlider.MinorTicks = [];
            app.PoleLocationSlider.Value = 0.5;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Time Waveforms')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Convolution Output')
            xlabel(app.UIAxes2, 'Freq')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = convolution

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end