classdef sin_LPF_reconstruct_2017 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlotButton                  matlab.ui.control.Button
        FinishButton                matlab.ui.control.Button
        SineKSpinnerLabel           matlab.ui.control.Label
        SineKSpinner                matlab.ui.control.Spinner
        TimeLengthSpinnerLabel      matlab.ui.control.Label
        TimeLengthSpinner           matlab.ui.control.Spinner
        NSpinnerLabel               matlab.ui.control.Label
        NSpinner                    matlab.ui.control.Spinner
        SineFreq2piNKKN2Label       matlab.ui.control.Label
        InterpolationFactorSpinnerLabel  matlab.ui.control.Label
        InterpolationFactorSpinner  matlab.ui.control.Spinner
        LPFLengthSpinnerLabel       matlab.ui.control.Label
        LPFLengthSpinner            matlab.ui.control.Spinner
        Label                       matlab.ui.control.Label
        UIAxes1                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = Process(app)
            close all
            % DSP MSC course
            % Demo program by Hamid Sheikhzadeh, Oct. 2020 to demonstrate
            % use of ZOH + LPF to  reconstruct a sine wave.
            hold( app.UIAxes1,'off');
            hold( app.UIAxes2,'off');
            hold( app.UIAxes3,'off');
            hold( app.UIAxes4,'off');
            time_len= app.TimeLengthSpinner.Value;
            sine_freq=app.SineKSpinner.Value; % Note the sin is NOT PERIODIC
            
            N= app.NSpinner.Value;
            if sine_freq >  N/2
                sine_freq= N-sine_freq;
                app.SineKSpinner.Value= sine_freq;
            end
            
            
            clc;
            
            LPFilter_Len=app.LPFLengthSpinner.Value;
            IntpFactor=app.InterpolationFactorSpinner.Value; % Use only EVEN numbers here
            Fs_new=IntpFactor;
            w0=sine_freq*2*pi/N; % play with this
            %w1=pi/3; % play with this
            % LPF
            Wn= 1/(IntpFactor*2); % Nyquist frequency, for LPF
            B=fir1(LPFilter_Len,Wn);
            A=1;
            [H,w]=freqz(B,A,1024);
            plot( app.UIAxes1,w,20*log10(abs(H)))
            title( app.UIAxes1,'LPF freq. Response');
            t_old=1:time_len;
            inp=sin(w0*(t_old-1)+pi/4); % + sin(w1*(t_old-1));
            plot(  app.UIAxes2, t_old,inp,'.-');
            grid( app.UIAxes2,"minor");
            title( app.UIAxes2, 'Input sine wave');
            % Apply sample and Hold to generate staircase waveform
            t1=1;
            for t= t_old
                inp_SH(t1)=inp(t);
                for k=1:IntpFactor
                    t1= t1+1;
                    inp_SH(t1)=inp(t);
                end;
            end;
            t_new= 1:t1;
            
            plot( app.UIAxes3, t_new,inp_SH,'.-');hold( app.UIAxes1,"on")
            grid( app.UIAxes3, 'on');title( app.UIAxes3, 'Input after S/H');
            %
            out= conv(B,inp_SH);
            out=out(LPFilter_Len/2+IntpFactor/2+1:end-LPFilter_Len/2+IntpFactor/2);
            out= out * max(inp)/max(out(fix(end/2):end));
            plot( app.UIAxes3, t_new,out,'r');
            
            reference=sin(w0/IntpFactor*(t_new-1)+pi/4); % +sin(w1/IntpFactor*(t_new-1));
            
            plot( app.UIAxes4, t_new,out,'r.-');hold( app.UIAxes4,"on")
            plot( app.UIAxes4, t_new,reference,'k.-');
            title( app.UIAxes4, 'Input and Output');
            plot( app.UIAxes4, t_new,(reference-out),'b.-');
            %title('Reconstruction error');
            
            %
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Process(app)
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
        end

        % Callback function: InterpolationFactorSpinner, 
        % LPFLengthSpinner, NSpinner, PlotButton, SineKSpinner, 
        % TimeLengthSpinner
        function PlotButtonPushed(app, event)
            Process(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.SineKSpinnerLabel = uilabel(app.UIFigure);
            app.SineKSpinnerLabel.Text = 'Sine K';
            app.SineKSpinner = uispinner(app.UIFigure);
            app.SineKSpinner.Limits = [1 15];
            app.SineKSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.SineKSpinner.Value = 1;
            app.TimeLengthSpinnerLabel = uilabel(app.UIFigure);
            app.TimeLengthSpinnerLabel.Text = 'Time Length';
            app.TimeLengthSpinner = uispinner(app.UIFigure);
            app.TimeLengthSpinner.Limits = [0 512];
            app.TimeLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.TimeLengthSpinner.Value = 256;
            app.NSpinnerLabel = uilabel(app.UIFigure);
            app.NSpinnerLabel.Text = 'N';
            app.NSpinner = uispinner(app.UIFigure);
            app.NSpinner.Limits = [4 16];
            app.NSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.NSpinner.Value = 8;
            app.SineFreq2piNKKN2Label = uilabel(app.UIFigure);
            app.SineFreq2piNKKN2Label.Text = 'Sine Freq= 2*pi/N*K,   K< N/2';
            app.InterpolationFactorSpinnerLabel = uilabel(app.UIFigure);
            app.InterpolationFactorSpinnerLabel.Text = 'Interpolation Factor';
            app.InterpolationFactorSpinner = uispinner(app.UIFigure);
            app.InterpolationFactorSpinner.Limits = [2 30];
            app.InterpolationFactorSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.InterpolationFactorSpinner.Value = 10;
            app.LPFLengthSpinnerLabel = uilabel(app.UIFigure);
            app.LPFLengthSpinnerLabel.Text = 'LPF Length';
            app.LPFLengthSpinner = uispinner(app.UIFigure);
            app.LPFLengthSpinner.Step = 2;
            app.LPFLengthSpinner.Limits = [14 128];
            app.LPFLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.LPFLengthSpinner.Value = 32;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' DSP MSC course, Demo program by Hamid Sheikhzadeh, Oct. 2020 to demonstrate ,  use of ZOH + LPF to  reconstruct a sine wave.';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, ' ')
            xlabel(app.UIAxes1, 'n')
            ylabel(app.UIAxes1, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, ' ')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes4, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sin_LPF_reconstruct_2017

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end