classdef sin_sincIntp_2017 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlotButton                  matlab.ui.control.Button
        FinishButton                matlab.ui.control.Button
        SineKSpinnerLabel           matlab.ui.control.Label
        SineKSpinner                matlab.ui.control.Spinner
        TimeLengthSpinnerLabel      matlab.ui.control.Label
        TimeLengthSpinner           matlab.ui.control.Spinner
        NSpinnerLabel               matlab.ui.control.Label
        NSpinner                    matlab.ui.control.Spinner
        SineFreq2piNKKN2Label       matlab.ui.control.Label
        SincLengthSpinnerLabel      matlab.ui.control.Label
        SincLengthSpinner           matlab.ui.control.Spinner
        InterpolationFactorSpinnerLabel  matlab.ui.control.Label
        InterpolationFactorSpinner  matlab.ui.control.Spinner
        OutputSNREditFieldLabel     matlab.ui.control.Label
        OutputSNREditField          matlab.ui.control.NumericEditField
        UIAxes1                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = Process(app)
                     
            % DSP BSC course
            % Demo program by Hamid Sheikhzadeh, Mar. 2020 to demonstrate 
            % sinc interpolation to reconstruct a sine wave.
            hold( app.UIAxes1,'off');
            hold( app.UIAxes2,'off');
            hold( app.UIAxes3,'off');
            hold( app.UIAxes4,'off');
            time_len= app.TimeLengthSpinner.Value;
            sine_freq=app.SineKSpinner.Value; % Note the sin is NOT PERIODIC
            t=0:1:time_len-1; 
            N= app.NSpinner.Value;
            if sine_freq >  N/2
                sine_freq= N-sine_freq;
                app.SineKSpinner.Value= sine_freq;
            end
            input_wave=sin(sine_freq*2*pi/N*t+ pi/4);
            %-------------------------------------------------------------
            % Sinc Interpolation
            Interpolation_factor=app.InterpolationFactorSpinner.Value; % Number of samples between points
            Sinc_len_by_2= app.SincLengthSpinner.Value; 
            sinc_wave=sinc(-Sinc_len_by_2:1/Interpolation_factor:Sinc_len_by_2);
            % Note: Make sure start and end points of sinc are ODD numbers,i.e -3:3, -5:5
            % otherwise the error would be much higher since the last lobes would not
            % be in phase with the main lobe! Compare -5:5 with -6:6 and you'll see the
            % effect.
            sinc_len=length(sinc_wave);
            %-------------------------------------------------------------
            output_wave_len=time_len*(Interpolation_factor+1);
            output_wave_time=0:1/Interpolation_factor:time_len-1;
            %
            output_wave=zeros(1,output_wave_len);
            st=1;
            for k=1:time_len
                en=st+sinc_len-1;
                if en>output_wave_len
                    break;
                end
                output_wave(st:en)= output_wave(st:en)+ input_wave(k)*sinc_wave;
                st= st+Interpolation_factor;
            end
            sinc_middle_point=fix(length(sinc_wave)/2);
            %---------------------------------------------------
            output_wave=output_wave(sinc_middle_point:end-sinc_len);
            output_wave_len=min(length(output_wave),length(output_wave_time));
            output_wave_time=output_wave_time(1:output_wave_len);
            Ideal_sine_wave= sin(sine_freq*2*pi/N*output_wave_time+pi/4);
            plot(app.UIAxes1, t,input_wave,'.-');
            grid(app.UIAxes1,'on')
            title(app.UIAxes1,'Input sine wave');
            %-----------------------------------------------
            plot(app.UIAxes2,sinc_wave,'*-');
            grid(app.UIAxes2,'on')
            title(app.UIAxes2, 'Interpolating Sinc');
            %-------------------------------------------------
            plot(app.UIAxes3, t,input_wave,'bo-');
            hold(app.UIAxes3,'on');
            grid(app.UIAxes3, 'on');
            %-----------------------------------
            plot(app.UIAxes3, output_wave_time,output_wave(1:output_wave_len),'r-');
            hold(app.UIAxes3,'on');grid(app.UIAxes3, 'on')
            plot(app.UIAxes3, output_wave_time,Ideal_sine_wave,'g')
            title(app.UIAxes3, 'Input (BLUE) and Output (RED), and Ideal Sine (GREEN) waves');
            %
            Error_time_wave= Ideal_sine_wave-output_wave(1:output_wave_len);
            plot(app.UIAxes4, output_wave_time,Error_time_wave);
            title(app.UIAxes4,'Error between the interploated and the true sine wave');
            % SNR in the mid-points
            Mid_segment= fix(length(Ideal_sine_wave)/2);
            Mid_segment_by2= fix( length(Ideal_sine_wave)/4);
            Ideal_Mid_segment= Ideal_sine_wave  (Mid_segment- Mid_segment_by2:Mid_segment+ Mid_segment_by2);
            Output_Mid_segment= output_wave_time(Mid_segment- Mid_segment_by2:Mid_segment+ Mid_segment_by2);
            Error_Mid_segment= Error_time_wave  (Mid_segment- Mid_segment_by2:Mid_segment+ Mid_segment_by2);
            SNR= 20*log10(norm(Ideal_Mid_segment,2)/norm(Error_Mid_segment,2) );
            app.OutputSNREditField.Value= SNR;            
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Process(app)
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            delete(app)
        end

        % Callback function: InterpolationFactorSpinner, NSpinner, 
        % PlotButton, SincLengthSpinner, SineKSpinner, 
        % TimeLengthSpinner
        function PlotButtonPushed(app, event)
            Process(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.SineKSpinnerLabel = uilabel(app.UIFigure);
            app.SineKSpinnerLabel.Text = 'Sine K';
            app.SineKSpinner = uispinner(app.UIFigure);
            app.SineKSpinner.Limits = [1 15];
            app.SineKSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.SineKSpinner.Value = 1;
            app.TimeLengthSpinnerLabel = uilabel(app.UIFigure);
            app.TimeLengthSpinnerLabel.Text = 'Time Length';
            app.TimeLengthSpinner = uispinner(app.UIFigure);
            app.TimeLengthSpinner.Limits = [0 512];
            app.TimeLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.TimeLengthSpinner.Value = 256;
            app.NSpinnerLabel = uilabel(app.UIFigure);
            app.NSpinnerLabel.Text = 'N';
            app.NSpinner = uispinner(app.UIFigure);
            app.NSpinner.Limits = [4 16];
            app.NSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.NSpinner.Value = 8;
            app.SineFreq2piNKKN2Label = uilabel(app.UIFigure);
            app.SineFreq2piNKKN2Label.Text = 'Sine Freq= 2*pi/N*K,   K< N/2';
            app.SincLengthSpinnerLabel = uilabel(app.UIFigure);
            app.SincLengthSpinnerLabel.Text = 'Sinc Length';
            app.SincLengthSpinner = uispinner(app.UIFigure);
            app.SincLengthSpinner.Limits = [3 64];
            app.SincLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.SincLengthSpinner.Value = 5;
            app.InterpolationFactorSpinnerLabel = uilabel(app.UIFigure);
            app.InterpolationFactorSpinnerLabel.Text = 'Interpolation Factor';
            app.InterpolationFactorSpinner = uispinner(app.UIFigure);
            app.InterpolationFactorSpinner.Limits = [2 30];
            app.InterpolationFactorSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.InterpolationFactorSpinner.Value = 10;
            app.OutputSNREditFieldLabel = uilabel(app.UIFigure);
            app.OutputSNREditFieldLabel.Text = 'Output SNR';
            app.OutputSNREditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, ' ')
            xlabel(app.UIAxes1, 'n')
            ylabel(app.UIAxes1, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, ' ')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes4, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sin_sincIntp_2017

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end